classdef motor_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ManualTab                    matlab.ui.container.Tab
        Yaw_home                     matlab.ui.control.Button
        MovetoEditFieldLabel         matlab.ui.control.Label
        Yaw_moveto                   matlab.ui.control.NumericEditField
        Yaw_stop                     matlab.ui.control.Button
        Pitch_connect                matlab.ui.control.StateButton
        MovetoEditField_2Label       matlab.ui.control.Label
        Pitch_moveto                 matlab.ui.control.NumericEditField
        Pitch_home                   matlab.ui.control.Button
        Pitch_stop                   matlab.ui.control.Button
        MoverelEditFieldLabel        matlab.ui.control.Label
        Yaw_moverel                  matlab.ui.control.NumericEditField
        MoverelEditField_2Label      matlab.ui.control.Label
        Pitch_moverel                matlab.ui.control.NumericEditField
        SerialEditFieldLabel         matlab.ui.control.Label
        Yaw_SerialNumber             matlab.ui.control.EditField
        SerialEditField_2Label       matlab.ui.control.Label
        Pitch_SerialNumber           matlab.ui.control.EditField
        Yaw_180reverse               matlab.ui.control.Button
        Yaw_90reverse                matlab.ui.control.Button
        Yaw_90forward                matlab.ui.control.Button
        Yaw_180forward               matlab.ui.control.Button
        Pitch_180reverse             matlab.ui.control.Button
        Pitch_90reverse              matlab.ui.control.Button
        Pitch_90forward              matlab.ui.control.Button
        Pitch_180forward             matlab.ui.control.Button
        Yaw_connect                  matlab.ui.control.StateButton
        VelocityEditFieldLabel       matlab.ui.control.Label
        Yaw_velocity                 matlab.ui.control.NumericEditField
        AccelerationLabel_2          matlab.ui.control.Label
        Yaw_acceleration             matlab.ui.control.NumericEditField
        VelocityEditField_2Label     matlab.ui.control.Label
        Pitch_velocity               matlab.ui.control.NumericEditField
        AccelerationLabel            matlab.ui.control.Label
        Pitch_acceleration           matlab.ui.control.NumericEditField
        PitchlimitEditFieldLabel     matlab.ui.control.Label
        Pitch_limit                  matlab.ui.control.NumericEditField
        AutomateTab                  matlab.ui.container.Tab
        Auto_initiatesequence        matlab.ui.control.Button
        YawstartposLabel             matlab.ui.control.Label
        Auto_Yaw_startpos            matlab.ui.control.NumericEditField
        PitchstartposEditFieldLabel  matlab.ui.control.Label
        Auto_Pitch_startpos          matlab.ui.control.NumericEditField
        HorizontalrangeLabel         matlab.ui.control.Label
        Auto_horizontalrange         matlab.ui.control.NumericEditField
        VerticalintervalsLabel       matlab.ui.control.Label
        Auto_verticalint             matlab.ui.control.NumericEditField
        VerticalrangeLabel           matlab.ui.control.Label
        Auto_verticalrange           matlab.ui.control.NumericEditField
        Auto_stop                    matlab.ui.control.Button
    end

	
	properties (Access = private)
		Yaw_motor % yaw motor object
		Pitch_motor% pitch motor object
		safety_buffer = 120;% size of safety buffer in degrees from the set app.pitch_limit
	end
	

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
			app.Yaw_SerialNumber.Value = '27254536';%yaw axis
			app.Pitch_SerialNumber.Value = '27254562';%pitch axis
			app.Yaw_motor = motor;%initiates motor object
			app.Pitch_motor = motor;%initiates motor object
			app.safety_buffer = 120;% sets safety buffer size
			%connects motors
			app.Yaw_connect.Value = 1;
			app.Pitch_connect.Value = 1;
			h1 = waitbar(0.01,'Connecting motors');
			connect(app.Yaw_motor,app.Yaw_SerialNumber.Value)
			connect(app.Pitch_motor,app.Pitch_SerialNumber.Value)
			%home both motors
			delete(h1);h1 = waitbar(0.2,'homing motors');
			home(app.Yaw_motor)
			home(app.Pitch_motor)
			app.Yaw_motor.deviceNET.Wait(app.Yaw_motor.TIMEOUTMOVE)
			app.Pitch_motor.deviceNET.Wait(app.Pitch_motor.TIMEOUTMOVE)
			updatestatus(app.Yaw_motor)
			updatestatus(app.Pitch_motor)
			app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			setvelocity(app.Yaw_motor,app.Yaw_velocity.Value,app.Yaw_velocity.Value)
			setvelocity(app.Pitch_motor,app.Pitch_velocity.Value,app.Pitch_velocity.Value)
			delete(h1);h1 = waitbar(1);delete(h1);
        end

        % Value changed function: Yaw_connect
        function Yaw_connectValueChanged(app, event)
			value = app.Yaw_connect.Value;
			if value==1
				connect(app.Yaw_motor,app.Yaw_SerialNumber.Value)
			elseif value==0
				disconnect(app.Yaw_motor)
			end
        end

        % Value changed function: Pitch_connect
        function Pitch_connectValueChanged(app, event)
			value = app.Pitch_connect.Value;
			if value==1
				connect(app.Pitch_motor,app.Pitch_SerialNumber.Value)
			elseif value==0
				disconnect(app.Pitch_motor)
			end
        end

        % Value changed function: Yaw_moveto
        function Yaw_movetoValueChanged(app, event)
			value = app.Yaw_moveto.Value;
			if value<0 || value>360
				error('Value should be positive and less than 360')
			else
				moveto(app.Yaw_motor,value)
				waitfor(app.Yaw_motor.deviceNET,'State','Idle')
				updatestatus(app.Yaw_motor)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
			
        end

        % Value changed function: Pitch_moveto
        function Pitch_movetoValueChanged(app, event)
			value = app.Pitch_moveto.Value;
			if value<0 || value>360
				error('Value should be positive and less than 360')
			else
				if app.Pitch_moveto.Value<app.Pitch_limit.Value && app.Pitch_moveto.Value>app.Pitch_limit.Value-app.safety_buffer
					%if value entered falls into safety buffer zone throw error
					error('This is past the safety limit')
				else
					moveto(app.Pitch_motor,value)
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor)
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			end
			
			
        end

        % Button pushed function: Yaw_180reverse
        function Yaw_180reverseButtonPushed(app, event)
			value = app.Yaw_moveto.Value-180; % get value from moveto edit field
			if value<0
				value = 360-abs(value);
				moveto(app.Yaw_motor,value);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor);
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			else
				moveto(app.Yaw_motor,value);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor);
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
			
        end

        % Button pushed function: Yaw_90reverse
        function Yaw_90reversePushed(app, event)
			value = app.Yaw_moveto.Value-90; % get value from moveto edit field
			if value<0
				value = 360-abs(value);
				moveto(app.Yaw_motor,value);
				updatestatus(app.Yaw_motor);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			else
				moveto(app.Yaw_motor,value);
				updatestatus(app.Yaw_motor);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
        end

        % Button pushed function: Yaw_90forward
        function Yaw_90forwardPushed(app, event)
			value = app.Yaw_moveto.Value+90; % get value from moveto edit field
			if value>360
				value = value-360;
				moveto(app.Yaw_motor,value);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor);
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			else
				moveto(app.Yaw_motor,value);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor);
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
			
        end

        % Button pushed function: Yaw_180forward
        function Yaw_180forwardPushed(app, event)
			value = app.Yaw_moveto.Value+180; % get value from moveto edit field
			if value>360
				value = value-360;
				moveto(app.Yaw_motor,value);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor);
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			else
				moveto(app.Yaw_motor,value);
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor);
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
			
        end

        % Button pushed function: Pitch_180reverse
        function Pitch_180reverseButtonPushed(app, event)
			value = app.Pitch_moveto.Value-180; % calculate next position
			safelim = app.Pitch_limit.Value + 180;% calculate safe position
			if safelim>360% if estimated safe position is greater than 360
				safelim = safelim-360;% adjust
			end
			if app.Pitch_moveto.Value<safelim% if current position read out is smaller than safepos
				error('This is past the safety limit') % travel will cross limit, throw error
			else
				if value<0 % if next position is negative
					value = 360-abs(value);%adjust
					moveto(app.Pitch_motor,value); %move there
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)% wait until motor is idle
					updatestatus(app.Pitch_motor);%update motor info
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);% display current position
				else % if next position is not negative, process as normal
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			end
        end

        % Button pushed function: Pitch_90reverse
        function Pitch_90reverseButtonPushed(app, event)
			value = app.Pitch_moveto.Value-90; % calculate next pos
			safelim = app.Pitch_limit.Value + 90; % calculate position from which travel would cross the safety limit
			if safelim>360
				safelim = 360-safelim;
			end
			if app.Pitch_moveto.Value<safelim % if the current readout value is less than safelim
				error('This will go past the safety limit')
			else
				if value<0
					value = 360-abs(value);
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				else
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			end
        end

        % Button pushed function: Pitch_90forward
        function Pitch_90forwardButtonPushed(app, event)
			value = app.Pitch_moveto.Value+90; % get value from moveto edit field
			safelim = app.Pitch_limit.Value-app.safety_buffer;%back edge of safety zone from the set pitch limit
			safelim = safelim-90; % safe pos
			if app.Pitch_moveto.Value>safelim
				error('This will go past the safety limit')
			else
				if value>360
					value = value-360;
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				else
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			end
        end

        % Button pushed function: Pitch_180forward
        function Pitch_180forwardButtonPushed(app, event)
			value = app.Pitch_moveto.Value+180; % get value from moveto edit field
			safelim = app.Pitch_limit-app.safety_buffer;
			safelim = safelim - 180;
			if app.Pitch_moveto.Value>safelim
				error('This is past the safety limit')
			else
				if value>360
					value = value-360;
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				else
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			end
        end

        % Button pushed function: Yaw_home
        function Yaw_homePushed(app, event)
			home(app.Yaw_motor)
			waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
			updatestatus(app.Yaw_motor);
			app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
        end

        % Button pushed function: Yaw_stop
        function Yaw_stopButtonPushed(app, event)
			if strcmp(app.Yaw_motor.deviceNET.State,'Idle')
				updatestatus(app.Yaw_motor)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			else
				stop(app.Yaw_motor)
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
        end

        % Value changed function: Yaw_moverel
        function Yaw_moverelValueChanged(app, event)
			%add sign check and limit check
			value = app.Yaw_moverel.Value;
			moverel_deviceunit(app.Yaw_motor,value)
			waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
			updatestatus(app.Yaw_motor)
			app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
        end

        % Value changed function: Pitch_moverel
        function Pitch_moverelValueChanged(app, event)
			safelim_front = app.Pitch_limit.Value-app.safety_buffer;
			safelim_back = app.Pitch_limit.Value;
			value = app.Pitch_moverel.Value;
			c_pos = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			mov = value*0.0005; %movement in degrees if 1 click = 0.0005 deg
			if value>0 %if value is positive i.e. moving forward
				n_pos = c_pos+mov;% estimate next position by assigning 0.0005 deg per click
				if n_pos>360;n_pos = npos-360;end % adjust estimate if value is above 360
				safelim = safelim_front-mov;% estimate safe position
				if n_pos>safelim% if next pos is closer than safe pos throw error
					error('This will go past the safety limit')
				else
					moverel_deviceunit(app.Pitch_motor,value)
					waitfor(app.Pitch_motor.deviceNET,'State','Idle')
					updatestatus(app.Pitch_motor)
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			else
				n_pos = c_pos-mov;
				if n_pos<0; n_pos = 360 - abs(n_pos);end
				safelim = safelim_back+mov;
				if safelim>360;safelim = safelim-360;end
				if n_pos<safelim
					error('This will go past safety limits')
				else
					moverel_deviceunit(app.Pitch_motor,value)
					waitfor(app.Pitch_motor.deviceNET,'State','Idle')
					updatestatus(app.Pitch_motor)
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			end
        end

        % Button pushed function: Pitch_home
        function Pitch_homeButtonPushed(app, event)
			%motor homes by first moving backwards, if current position is close to
			% 360 on the 359 side, the motor will go all the way around to find home.
			% this is also why motor should always be homed before shutdown.
			if app.Pitch_motor.deviceNET.Position>210 && app.Pitch_motor.deviceNET.Position<360
				moveto(app.Pitch_motor,0); % moves motor to zero before homing to avoid this
				waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
				home(app.Pitch_motor);
				waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Pitch_motor)
				app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			else
				home(app.Pitch_motor)
				waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Pitch_motor)
				app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			end
        end

        % Button pushed function: Pitch_stop
        function Pitch_stopButtonPushed(app, event)
			if strcmp(app.Pitch_motor.deviceNET.State,'Idle')
				updatestatus(app.Pitch_motor)
				app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			else
				stop(app.Pitch_motor)
				waitfor(app.Pitch_motor.deviceNET,'State','Idle')
				updatestatus(app.Pitch_motor)
				app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			end
        end

        % Value changed function: Yaw_velocity
        function Yaw_velocityValueChanged(app, event)
			value = app.Yaw_velocity.Value;
			setvelocity(app.Yaw_motor,value)
        end

        % Value changed function: Yaw_acceleration
        function Yaw_accelerationValueChanged(app, event)
			value = app.Yaw_acceleration.Value;
			setvelocity(app.Yaw_motor,app.Yaw_velocity.Value,value)
        end

        % Value changed function: Pitch_velocity
        function Pitch_velocityValueChanged(app, event)
			value = app.Pitch_velocity.Value;
			setvelocity(app.Pitch_motor,value)
        end

        % Value changed function: Pitch_acceleration
        function Pitch_accelerationValueChanged(app, event)
			value = app.Pitch_acceleration.Value;
			setvelocity(app.Pitch_motor,app.Pitch_velocity.Value,value)
        end

        % Value changed function: Auto_Yaw_startpos
        function Auto_Yaw_startposValueChanged(app, event)
			value = app.Auto_Yaw_startpos.Value;
			if value<0 || value>360
				error('Value should be positive and less than 360')
			else
				moveto(app.Yaw_motor,value)
				waitfor(app.Yaw_motor.deviceNET,'State','Idle')
				updatestatus(app.Yaw_motor)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
			
        end

        % Value changed function: Auto_Pitch_startpos
        function Auto_Pitch_startposValueChanged(app, event)
			value = app.Auto_Pitch_startpos.Value;
			if value<0 || value>360
				error('Value should be positive and less than 360')
			else
				if value<app.Pitch_limit.Value && value>app.Pitch_limit.Value-app.safety_buffer
					error('This is past the safety limit')
				else
					moveto(app.Pitch_motor,value);
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					updatestatus(app.Pitch_motor);
					app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
				end
			end
        end

        % Button pushed function: Auto_stop
        function Auto_stopButtonPushed(app, event)
			if strcmp(app.Yaw_motor.deviceNET.State,'Idle')
				updatestatus(app.Pitch_motor)
				app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			else
				stop(app.Pitch_motor)
				waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Pitch_motor)
				app.Pitch_moveto.Value = System.Decimal.ToDouble(app.Pitch_motor.deviceNET.Position);
			end
			
			if strcmp(app.Yaw_motor.deviceNET.State,'Idle')
				updatestatus(app.Yaw_motor)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			else
				stop(app.Yaw_motor)
				waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
				updatestatus(app.Yaw_motor)
				app.Yaw_moveto.Value = System.Decimal.ToDouble(app.Yaw_motor.deviceNET.Position);
			end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
				if app.Yaw_motor.isconnected
					home(app.Yaw_motor)
					waitfor(app.Yaw_motor.deviceNET,'State','Idle');pause(0)
					disconnect(app.Yaw_motor)
					pause(0.1)
				end
				if app.Pitch_motor.isconnected
					home(app.Pitch_motor)
					waitfor(app.Pitch_motor.deviceNET,'State','Idle');pause(0)
					disconnect(app.Pitch_motor)
					pause(0.1)
				end
				delete(app)
        end

        % Button pushed function: Auto_initiatesequence
        function Auto_initiatesequenceButtonPushed(app, event)
            horzrange = app.Auto_horizontalrange.Value;
			vertrange = app.Auto_verticalrange.Value;
			vertint = app.Auto_verticalint.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.Yaw_home = uibutton(app.ManualTab, 'push');
            app.Yaw_home.ButtonPushedFcn = createCallbackFcn(app, @Yaw_homePushed, true);
            app.Yaw_home.Text = 'Go Home';
            app.MovetoEditFieldLabel = uilabel(app.ManualTab);
            app.MovetoEditFieldLabel.Text = 'Move to';
            app.Yaw_moveto = uieditfield(app.ManualTab, 'numeric');
            app.Yaw_moveto.ValueDisplayFormat = '%.2f';
            app.Yaw_moveto.ValueChangedFcn = createCallbackFcn(app, @Yaw_movetoValueChanged, true);
            app.Yaw_stop = uibutton(app.ManualTab, 'push');
            app.Yaw_stop.ButtonPushedFcn = createCallbackFcn(app, @Yaw_stopButtonPushed, true);
            app.Yaw_stop.Text = 'Stop';
            app.Pitch_connect = uibutton(app.ManualTab, 'state');
            app.Pitch_connect.ValueChangedFcn = createCallbackFcn(app, @Pitch_connectValueChanged, true);
            app.Pitch_connect.Text = 'Connect Pitch';
            app.MovetoEditField_2Label = uilabel(app.ManualTab);
            app.MovetoEditField_2Label.Text = 'Move to';
            app.Pitch_moveto = uieditfield(app.ManualTab, 'numeric');
            app.Pitch_moveto.ValueDisplayFormat = '%.2f';
            app.Pitch_moveto.ValueChangedFcn = createCallbackFcn(app, @Pitch_movetoValueChanged, true);
            app.Pitch_home = uibutton(app.ManualTab, 'push');
            app.Pitch_home.ButtonPushedFcn = createCallbackFcn(app, @Pitch_homeButtonPushed, true);
            app.Pitch_home.Text = 'Go Home';
            app.Pitch_stop = uibutton(app.ManualTab, 'push');
            app.Pitch_stop.ButtonPushedFcn = createCallbackFcn(app, @Pitch_stopButtonPushed, true);
            app.Pitch_stop.Text = 'Stop';
            app.MoverelEditFieldLabel = uilabel(app.ManualTab);
            app.MoverelEditFieldLabel.Text = 'Move rel.';
            app.Yaw_moverel = uieditfield(app.ManualTab, 'numeric');
            app.Yaw_moverel.ValueChangedFcn = createCallbackFcn(app, @Yaw_moverelValueChanged, true);
            app.Yaw_moverel.Tooltip = {'Moves relative to current position (1 click = 0.0005 deg)'};
            app.MoverelEditField_2Label = uilabel(app.ManualTab);
            app.MoverelEditField_2Label.Text = 'Move rel.';
            app.Pitch_moverel = uieditfield(app.ManualTab, 'numeric');
            app.Pitch_moverel.ValueChangedFcn = createCallbackFcn(app, @Pitch_moverelValueChanged, true);
            app.Pitch_moverel.Tooltip = {'Moves relative to current position (1 click = 0.0005deg)'};
            app.SerialEditFieldLabel = uilabel(app.ManualTab);
            app.SerialEditFieldLabel.Text = 'Serial';
            app.Yaw_SerialNumber = uieditfield(app.ManualTab, 'text');
            app.Yaw_SerialNumber.Value = '27254536';
            app.SerialEditField_2Label = uilabel(app.ManualTab);
            app.SerialEditField_2Label.Text = 'Serial';
            app.Pitch_SerialNumber = uieditfield(app.ManualTab, 'text');
            app.Pitch_SerialNumber.Value = '27254562';
            app.Yaw_180reverse = uibutton(app.ManualTab, 'push');
            app.Yaw_180reverse.ButtonPushedFcn = createCallbackFcn(app, @Yaw_180reverseButtonPushed, true);
            app.Yaw_180reverse.Text = '-180';
            app.Yaw_90reverse = uibutton(app.ManualTab, 'push');
            app.Yaw_90reverse.ButtonPushedFcn = createCallbackFcn(app, @Yaw_90reversePushed, true);
            app.Yaw_90reverse.Text = '-90';
            app.Yaw_90forward = uibutton(app.ManualTab, 'push');
            app.Yaw_90forward.ButtonPushedFcn = createCallbackFcn(app, @Yaw_90forwardPushed, true);
            app.Yaw_90forward.Text = '+90';
            app.Yaw_180forward = uibutton(app.ManualTab, 'push');
            app.Yaw_180forward.ButtonPushedFcn = createCallbackFcn(app, @Yaw_180forwardPushed, true);
            app.Yaw_180forward.Text = '+180';
            app.Pitch_180reverse = uibutton(app.ManualTab, 'push');
            app.Pitch_180reverse.ButtonPushedFcn = createCallbackFcn(app, @Pitch_180reverseButtonPushed, true);
            app.Pitch_180reverse.Text = '-180';
            app.Pitch_90reverse = uibutton(app.ManualTab, 'push');
            app.Pitch_90reverse.ButtonPushedFcn = createCallbackFcn(app, @Pitch_90reverseButtonPushed, true);
            app.Pitch_90reverse.Text = '-90';
            app.Pitch_90forward = uibutton(app.ManualTab, 'push');
            app.Pitch_90forward.ButtonPushedFcn = createCallbackFcn(app, @Pitch_90forwardButtonPushed, true);
            app.Pitch_90forward.Text = '+90';
            app.Pitch_180forward = uibutton(app.ManualTab, 'push');
            app.Pitch_180forward.ButtonPushedFcn = createCallbackFcn(app, @Pitch_180forwardButtonPushed, true);
            app.Pitch_180forward.Text = '+180';
            app.Yaw_connect = uibutton(app.ManualTab, 'state');
            app.Yaw_connect.ValueChangedFcn = createCallbackFcn(app, @Yaw_connectValueChanged, true);
            app.Yaw_connect.Text = 'Connect Yaw';
            app.VelocityEditFieldLabel = uilabel(app.ManualTab);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.Yaw_velocity = uieditfield(app.ManualTab, 'numeric');
            app.Yaw_velocity.Limits = [0 25];
            app.Yaw_velocity.ValueChangedFcn = createCallbackFcn(app, @Yaw_velocityValueChanged, true);
            app.Yaw_velocity.Value = 2.5;
            app.AccelerationLabel_2 = uilabel(app.ManualTab);
            app.AccelerationLabel_2.Text = 'Acceleration';
            app.Yaw_acceleration = uieditfield(app.ManualTab, 'numeric');
            app.Yaw_acceleration.Limits = [0 25];
            app.Yaw_acceleration.ValueChangedFcn = createCallbackFcn(app, @Yaw_accelerationValueChanged, true);
            app.Yaw_acceleration.Value = 2.5;
            app.VelocityEditField_2Label = uilabel(app.ManualTab);
            app.VelocityEditField_2Label.Text = 'Velocity';
            app.Pitch_velocity = uieditfield(app.ManualTab, 'numeric');
            app.Pitch_velocity.Limits = [0 25];
            app.Pitch_velocity.ValueChangedFcn = createCallbackFcn(app, @Pitch_velocityValueChanged, true);
            app.Pitch_velocity.Value = 2.5;
            app.AccelerationLabel = uilabel(app.ManualTab);
            app.AccelerationLabel.Text = 'Acceleration';
            app.Pitch_acceleration = uieditfield(app.ManualTab, 'numeric');
            app.Pitch_acceleration.Limits = [0 25];
            app.Pitch_acceleration.ValueChangedFcn = createCallbackFcn(app, @Pitch_accelerationValueChanged, true);
            app.Pitch_acceleration.Value = 2.5;
            app.PitchlimitEditFieldLabel = uilabel(app.ManualTab);
            app.PitchlimitEditFieldLabel.Text = 'Pitch limit';
            app.Pitch_limit = uieditfield(app.ManualTab, 'numeric');
            app.Pitch_limit.Value = 340;
            app.AutomateTab = uitab(app.TabGroup);
            app.AutomateTab.Title = 'Automate';
            app.Auto_initiatesequence = uibutton(app.AutomateTab, 'push');
            app.Auto_initiatesequence.ButtonPushedFcn = createCallbackFcn(app, @Auto_initiatesequenceButtonPushed, true);
            app.Auto_initiatesequence.Text = 'Initiate sequence';
            app.YawstartposLabel = uilabel(app.AutomateTab);
            app.YawstartposLabel.Text = 'Yaw startpos';
            app.Auto_Yaw_startpos = uieditfield(app.AutomateTab, 'numeric');
            app.Auto_Yaw_startpos.ValueDisplayFormat = '%.2f';
            app.Auto_Yaw_startpos.ValueChangedFcn = createCallbackFcn(app, @Auto_Yaw_startposValueChanged, true);
            app.PitchstartposEditFieldLabel = uilabel(app.AutomateTab);
            app.PitchstartposEditFieldLabel.Text = 'Pitch startpos';
            app.Auto_Pitch_startpos = uieditfield(app.AutomateTab, 'numeric');
            app.Auto_Pitch_startpos.ValueDisplayFormat = '%.2f';
            app.Auto_Pitch_startpos.ValueChangedFcn = createCallbackFcn(app, @Auto_Pitch_startposValueChanged, true);
            app.HorizontalrangeLabel = uilabel(app.AutomateTab);
            app.HorizontalrangeLabel.Text = 'Horizontal range';
            app.Auto_horizontalrange = uieditfield(app.AutomateTab, 'numeric');
            app.Auto_horizontalrange.Limits = [0 180];
            app.Auto_horizontalrange.Value = 5;
            app.VerticalintervalsLabel = uilabel(app.AutomateTab);
            app.VerticalintervalsLabel.Text = 'Vertical intervals';
            app.Auto_verticalint = uieditfield(app.AutomateTab, 'numeric');
            app.Auto_verticalint.Value = 1;
            app.VerticalrangeLabel = uilabel(app.AutomateTab);
            app.VerticalrangeLabel.Text = 'Vertical range';
            app.Auto_verticalrange = uieditfield(app.AutomateTab, 'numeric');
            app.Auto_verticalrange.Value = 5;
            app.Auto_stop = uibutton(app.AutomateTab, 'push');
            app.Auto_stop.ButtonPushedFcn = createCallbackFcn(app, @Auto_stopButtonPushed, true);
            app.Auto_stop.Text = 'Stop All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end